classdef settingsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        themesMenu            matlab.ui.control.Button
        ColorPicker           matlab.ui.control.ColorPicker
        ColorPickerLabel      matlab.ui.control.Label
        exitButton            matlab.ui.control.Button
        playPauseMusicButton  matlab.ui.control.StateButton
        ThemesButtonGroup     matlab.ui.container.ButtonGroup
        Button_3              matlab.ui.control.ToggleButton
        Button_2              matlab.ui.control.ToggleButton
        Button                matlab.ui.control.ToggleButton
    end

    properties (Access = private)
        MainApp % Description
    end
   %% SOUNDS 
    methods (Access = public)
        
        function [] = Click(app)
            [y, Fs] = audioread("CLICK.mp3");
            player = audioplayer(3.5*y, Fs);
            play(player);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, background, music)
          app.playPauseMusicButton.Value = music;
           app.ColorPicker.Value = background;
          app.MainApp = mainapp;
        end

        % Value changed function: playPauseMusicButton
        function playPauseMusicButtonValueChanged(app, event)
            value = app.playPauseMusicButton.Value;
            if value == 1
                app.playPauseMusicButton.Icon = fullfile(pathToMLAPP, 'pauseSoundButton.png');
                % play(blahblahblah(app)) for sound
            else
                app.playPauseMusicButton.Icon = fullfile(pathToMLAPP, 'playSoundButton.png');
               % to end sound pause(blahblahblah(app))
            end
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
            app.MainApp.RummyUIFigure.Color = app.ColorPicker.Value;
            app.MainApp.settingsButton.Enable = 'on'
            delete(app)

            Click(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
            app.MainApp.settingsButton.Enable = 'on';
            end
            delete(app);
        end

        % Callback function: ColorPicker
        function ColorPickerValueChanged(app, event)
      
      app.MainApp.RummyUIFigure.Color = app.ColorPicker.Value;
      background = app.ColorPicker.Value
      mainAppColor = app.MainApp.RummyUIFigure.Color
        end

        % Button pushed function: themesMenu
        function themesMenuPushed(app, event)
            app.ThemesButtonGroup.Visible = 'on';
            app.ColorPicker.Visible = 'on';
            app.themesMenu.Visible = 'off';
            app.playPauseMusicButton.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ThemesButtonGroup = uibuttongroup(app.UIFigure);
            app.ThemesButtonGroup.Title = 'Themes';
            app.ThemesButtonGroup.Visible = 'off';
            app.Button = uitogglebutton(app.ThemesButtonGroup);
            app.Button.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'defaultButton.png');
            app.Button.Text = '';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.ThemesButtonGroup);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'starwarsButton.png');
            app.Button_2.Text = '';
            app.Button_3 = uitogglebutton(app.ThemesButtonGroup);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'quadButton.png');
            app.Button_3.Text = '';
            app.playPauseMusicButton = uibutton(app.UIFigure, 'state');
            app.playPauseMusicButton.ValueChangedFcn = createCallbackFcn(app, @playPauseMusicButtonValueChanged, true);
            app.playPauseMusicButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'pauseSoundButton.png');
            app.playPauseMusicButton.Text = '';
            app.playPauseMusicButton.Value = true;
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'okButton.png');
            app.exitButton.Text = '';
            app.ColorPickerLabel = uilabel(app.UIFigure);
            app.ColorPickerLabel.Visible = 'off';
            app.ColorPickerLabel.Text = 'Color Picker';
            app.ColorPicker = uicolorpicker(app.UIFigure);
            app.ColorPicker.ValueChangedFcn = createCallbackFcn(app, @ColorPickerValueChanged, true);
            app.ColorPicker.Visible = 'off';
            app.themesMenu = uibutton(app.UIFigure, 'push');
            app.themesMenu.ButtonPushedFcn = createCallbackFcn(app, @themesMenuPushed, true);
            app.themesMenu.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'themesButton.png');
            app.themesMenu.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = settingsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end